classdef App_SISSOMA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Image5                   matlab.ui.control.Image
        TemperatureSpinner       matlab.ui.control.Spinner
        TemperatureSpinnerLabel  matlab.ui.control.Label
        TurbulenceSpinner        matlab.ui.control.Spinner
        TurbulenceSpinnerLabel   matlab.ui.control.Label
        Image3                   matlab.ui.control.Image
        Image2                   matlab.ui.control.Image
        SeasonalitySwitch        matlab.ui.control.Switch
        SeasonalitySwitchLabel   matlab.ui.control.Label
        StickinessSpinner        matlab.ui.control.Spinner
        StickinessSpinnerLabel   matlab.ui.control.Label
        SelfsimSpinner           matlab.ui.control.Spinner
        Spinner2Label            matlab.ui.control.Label
        PlotButton               matlab.ui.control.Button
        Image4                   matlab.ui.control.Image
        UIAxes                   matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)


            app2 = get(findall(0, 'Tag', 'AppUIFigure'), 'RunningAppInstance')
            
            selfsim = app.SelfsimSpinner.Value;
            sticki = app.StickinessSpinner.Value; % stickiness; range 0 to 1
            epsilon = 1E-6;  % turbulent dissipation rate [m3/s2]
            Ptotal = 1E6;    % total productivity; typically 1E6 [µg m-2 day-1] (1 gC m-2 day-1)
            Rrate = 0.1;     % remineralization rate [day-1]
            Frate = 500;     % maximum fragmentation rate [day-1] for aggregates > 1 m
            Tmax = 2*365;    % period of simulation [days]

            if app.SeasonalitySwitch == 0

                seasonal = 0;   % seasonal (true) or constant (false) production
            else
                seasonal = 1;   % seasonal (true) or constant (false) production
            end

            T_input = 10;

            sim = coagfunDev(selfsim,sticki,epsilon,Ptotal,Rrate,Frate,Tmax,seasonal,T_input);


            % SOS!!!!!!!!!!! Create new function for plotting !!!!!!!!!!

            myCols = {[0.2 0.5 0.9 1] [0.7 0.5 0.9 1] [0.2 0.1 0.2 1]  [0.6 0.9 0.3 1]};
            myShades = {[0.2 0.5 0.9 0.08] [0.7 0.5 0.9 0.08] [0.2 0.1 0.2 0.08]  [0.6 0.9 0.3 0.08]};
            LY = length(sim.t)-366:length(sim.t);

            Mdry = zeros(length(LY), sim.Nd, sim.Nr);
            Flux = zeros(length(LY), sim.Nd, sim.Nr);
            BFlux = zeros(length(LY), sim.Nr);

            for iTime = 1:length(LY)

                % dry mass per volume in each time step in each size-density bin [µgC/m3]
                Mdry(iTime,:,:) = reshape(squeeze(sim.Mo(LY(iTime),:)),sim.Nd,sim.Nr);

                % flux out of the H=50m mixed layer  [µgC/m2/day]
                Flux(iTime,:,:) = squeeze(Mdry(iTime,:,:)).*sim.w;

                % Size integrated flux [µgC/m2/day]
                BFlux(iTime,:) = sum(squeeze(Flux(iTime,:,:)),1);
            end


            for k = 1:length(LY)

                semilogx(app.UIAxes, sim.r_mean, BFlux(k,:), 'LineWidth', 0.2, 'Color', myShades{2})
                hold(app.UIAxes,'on');
            end

            Bmean = mean(BFlux,1);
            semilogx(app.UIAxes, sim.r_mean, Bmean, '-o', 'LineWidth', 1, 'Color', myCols{2});

            hold(app.UIAxes,'off');


        end

        % Callback function
        function EditFieldValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Size-integrated Flux')
            xlabel(app.UIAxes, 'radius [\mum]')
            ylabel(app.UIAxes, 'flux [\mugC/ m2/ day]')
            zlabel(app.UIAxes, 'Z')
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.Enable = 'off';
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'background 4.jpg');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Spinner2Label = uilabel(app.UIFigure);
            app.Spinner2Label.Text = 'Self-similarity';
            app.SelfsimSpinner = uispinner(app.UIFigure);
            app.SelfsimSpinner.Step = 0.1;
            app.SelfsimSpinner.Limits = [1.5 2.9];
            app.SelfsimSpinner.Value = 2;
            app.StickinessSpinnerLabel = uilabel(app.UIFigure);
            app.StickinessSpinnerLabel.Text = 'Stickiness';
            app.StickinessSpinner = uispinner(app.UIFigure);
            app.StickinessSpinner.Step = 0.1;
            app.StickinessSpinner.Limits = [0 1];
            app.StickinessSpinner.Value = 0.4;
            app.SeasonalitySwitchLabel = uilabel(app.UIFigure);
            app.SeasonalitySwitchLabel.Text = 'Seasonality';
            app.SeasonalitySwitch = uiswitch(app.UIFigure, 'slider');
            app.SeasonalitySwitch.Value = 'On';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'dtu logo.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'logo-centre-of-ocean-life.jpg');
            app.TurbulenceSpinnerLabel = uilabel(app.UIFigure);
            app.TurbulenceSpinnerLabel.Text = 'Turbulence';
            app.TurbulenceSpinner = uispinner(app.UIFigure);
            app.TurbulenceSpinner.Step = 1e-05;
            app.TurbulenceSpinner.Limits = [1e-06 0.001];
            app.TurbulenceSpinner.Value = 1e-06;
            app.TemperatureSpinnerLabel = uilabel(app.UIFigure);
            app.TemperatureSpinnerLabel.Text = 'Temperature';
            app.TemperatureSpinner = uispinner(app.UIFigure);
            app.TemperatureSpinner.Limits = [0 20];
            app.TemperatureSpinner.Value = 10;
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'logo ECOTIP.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_SISSOMA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end